classdef F20 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        SingleComp                 matlab.ui.container.Tab
        FilesLoadedListBoxLabel    matlab.ui.control.Label
        FilesLoadedListBox         matlab.ui.control.ListBox
        LoadDataButton             matlab.ui.control.Button
        TabGroup2                  matlab.ui.container.TabGroup
        DamperTab                  matlab.ui.container.Tab
        UIAxes2                    matlab.ui.control.UIAxes
        FrontDampersLabel          matlab.ui.control.Label
        RearDampersLabel           matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        TireCompareTab             matlab.ui.container.Tab
        test3Tab                   matlab.ui.container.Tab
        DualComparisonTab          matlab.ui.container.Tab
        LoadDataButton_2           matlab.ui.control.Button
        FilesLoadedListBox_2Label  matlab.ui.control.Label
        FilesLoadedListBox_2       matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        filesLoaded = {}; % Description
    end
    

    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file, path] = uigetfile('.xlsx','MultiSelect','on');
            fileName = [path file];
            if strcmp(app.FilesLoadedListBox.Items,'None')
                app.FilesLoadedListBox.Items = {file};
            end
            
            Damper_Analysis_Vehicle_Data_GUI(fileName);
            
            
        end

        % Value changed function: FilesLoadedListBox
        function FilesLoadedListBoxValueChanged(app, event)
            value = app.FilesLoadedListBox.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleComp = uitab(app.TabGroup);
            app.SingleComp.Title = 'Single Comparison';
            app.FilesLoadedListBoxLabel = uilabel(app.SingleComp);
            app.FilesLoadedListBoxLabel.Text = 'Files Loaded';
            app.FilesLoadedListBox = uilistbox(app.SingleComp);
            app.FilesLoadedListBox.Items = {'None'};
            app.FilesLoadedListBox.ValueChangedFcn = createCallbackFcn(app, @FilesLoadedListBoxValueChanged, true);
            app.FilesLoadedListBox.Value = {};
            app.LoadDataButton = uibutton(app.SingleComp, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = {'Load Data'; ''};
            app.TabGroup2 = uitabgroup(app.SingleComp);
            app.DamperTab = uitab(app.TabGroup2);
            app.DamperTab.Title = 'Damper';
            app.UIAxes2 = uiaxes(app.DamperTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.FrontDampersLabel = uilabel(app.DamperTab);
            app.FrontDampersLabel.Text = 'Front Dampers';
            app.RearDampersLabel = uilabel(app.DamperTab);
            app.RearDampersLabel.Text = 'Rear Dampers';
            app.UIAxes = uiaxes(app.DamperTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TireCompareTab = uitab(app.TabGroup2);
            app.TireCompareTab.Title = 'Tire Compare';
            app.test3Tab = uitab(app.TabGroup2);
            app.test3Tab.Title = 'test3';
            app.DualComparisonTab = uitab(app.TabGroup);
            app.DualComparisonTab.Title = 'Dual Comparison';
            app.LoadDataButton_2 = uibutton(app.DualComparisonTab, 'push');
            app.LoadDataButton_2.Text = {'Load Data'; ''};
            app.FilesLoadedListBox_2Label = uilabel(app.DualComparisonTab);
            app.FilesLoadedListBox_2Label.Text = 'Files Loaded';
            app.FilesLoadedListBox_2 = uilistbox(app.DualComparisonTab);
            app.FilesLoadedListBox_2.Items = {'None'};
            app.FilesLoadedListBox_2.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = F20

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end